classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        QuantitativeIndicatorsPanel     matlab.ui.container.Panel
        ValueArea                       matlab.ui.control.TextArea
        EvaluateButton                  matlab.ui.control.Button
        SelectfolderButton              matlab.ui.control.Button
        Title                           matlab.ui.control.Label
        Group36                         matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SelectImageTab                  matlab.ui.container.Tab
        ReferenceDropDownLabel          matlab.ui.control.Label
        ReferenceDropDown               matlab.ui.control.DropDown
        TargetDropDownLabel             matlab.ui.control.Label
        TargetDropDown                  matlab.ui.control.DropDown
        IlluminationNormalizationButton  matlab.ui.control.Button
        TarAxes                         matlab.ui.control.UIAxes
        TarNormAxes                     matlab.ui.control.UIAxes
        RefAxes                         matlab.ui.control.UIAxes
        TimeLapseTab                    matlab.ui.container.Tab
        TimeLapsePanel                  matlab.ui.container.Panel
        ShowTimeLapseButton             matlab.ui.control.Button
        CurrentfolderLabel              matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        RegistrationTab                 matlab.ui.container.Tab
        RegistrationofSelectedImagesButton  matlab.ui.control.StateButton
        RegistrationofAllImagesButton   matlab.ui.control.StateButton
        RegistrationAxes                matlab.ui.control.UIAxes
        AnimationAxes                   matlab.ui.control.UIAxes
        DifferentiationTab              matlab.ui.container.Tab
        DifferentiationButton           matlab.ui.control.StateButton
        HighlightButton                 matlab.ui.control.StateButton
        DifferentiationThresholdSliderLabel  matlab.ui.control.Label
        DifferentiationThresholdSlider  matlab.ui.control.Slider
        DiffAxes                        matlab.ui.control.UIAxes
        HighlightAxes                   matlab.ui.control.UIAxes
        ClassificationTab               matlab.ui.container.Tab
        HighlightEvaluateButton         matlab.ui.control.Button
        ClassTextArea                   matlab.ui.control.TextArea
        UrbanAxes                       matlab.ui.control.UIAxes
        NatureAxes                      matlab.ui.control.UIAxes
        ResetButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        path
        filefolder
        image
        date
        idx_ref
        image_ref
        idx_tar
        image_tar
        image_tar_norm
        image_align
        im_gif
        threshold
        image_di
        image_highlight
        number_AlignedPixels
        number_DifferentPixels
        ch_ratio
        ch_speed
        isUrban
        num_month 
        highlight_nature
        highlight_urban
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.idx_ref = app.ReferenceDropDown.Value;
            app.ReferenceDropDown.Items = arrayfun(@num2str,app.date,'UniformOutput',false);
            app.ReferenceDropDown.ItemsData = 1:numel(app.date);
        end

        % Button pushed function: SelectfolderButton
        function ChooseFolder(app, event)
            app.UIFigure.Visible = 'off';
            folder = uigetdir('','Please choose an image folder.');
            app.CurrentfolderLabel.Text = ['Current folder: ' folder];
            app.UIFigure.Visible = 'on';
            namelist = dir([folder '/*.jpg']);
            len = length(namelist);   
            app.path = cell(1,len);
            for i = 1:len
                app.path{i}=[namelist(i).folder '/' namelist(i).name];
            end
            [app.filefolder,filename,~] = fileparts(app.path);           
            year = zeros(1,len);
            month = zeros(1,len);
            value = zeros(1,len);
            for i = 1:len
                year(i) = str2double(filename{i}(1:4));
                month(i) = str2double(filename{i}(6:7));
                value(i) = year(i)+0.01*month(i);
            end
            app.image = cell(1,len);
            [app.date,idx] = sort(value,'ascend');
            for i = 1:len
                app.image{i} = imread(app.path{idx(i)});
            end
            
        end

        % Button pushed function: ShowTimeLapseButton
        function ShowTimeLapse(app, event)
            len = numel(app.image);
            tlo = tiledlayout(app.TimeLapsePanel,1,len,'Tilespacing','none','Padding','none');
            app.TimeLapsePanel.AutoResizeChildren = 'off';
            for i = 1:len
                im = insertText(app.image{i},[1 1],num2str(app.date(i),'%.2f'),'FontSize',150,'BoxColor',...
                    'black','BoxOpacity',0.4,'TextColor','white');
                ax = nexttile(tlo);
                imshow(im,'Parent',ax);
            end
            
        end

        % Drop down opening function: ReferenceDropDown
        function ChooseReference(app, event)
            app.ReferenceDropDown.Items = [{''} arrayfun(@(x) num2str(x,'%.2f'),app.date,'UniformOutput',false)];
            app.ReferenceDropDown.ItemsData = 0:numel(app.date);
            app.ReferenceDropDown.Value = 0;
        end

        % Value changed function: ReferenceDropDown
        function ValueRef(app, event)
            app.idx_ref = app.ReferenceDropDown.Value;
            app.image_ref = app.image{app.idx_ref};
            imshow(app.image_ref, 'parent', app.RefAxes);
        end

        % Value changed function: TargetDropDown
        function ValueTar(app, event)
            app.idx_tar = app.TargetDropDown.Value;
            app.image_tar = app.image{app.idx_tar};
            imshow(app.image_tar, 'parent', app.TarAxes);
        end

        % Drop down opening function: TargetDropDown
        function ChooseTarget(app, event)
            if isequal(app.idx_ref,{})
                fig = uifigure;
                msg = 'Pleas choose reference image first!';
                title = 'ERROR';
                selection = uiconfirm(fig,msg,title,...
                    'Options',{'OK'},'Icon','warning');
                switch selection
                    case 'OK'
                        delete(fig);
                end
            else
                app.TargetDropDown.Items = [{''} arrayfun(@(x) num2str(x,'%.2f'),app.date(app.idx_ref+1:end),'UniformOutput',false)];
                app.TargetDropDown.ItemsData = app.idx_ref:numel(app.date);
                app.TargetDropDown.Value = app.idx_ref;
            end            
        end

        % Value changed function: RegistrationofSelectedImagesButton
        function Registration(app, event)
            if app.idx_ref == app.idx_tar
                fig = uifigure;
                msg = 'Reference image and target image cannot be the same!';
                title = 'ERROR';
                selection = uiconfirm(fig,msg,title,...
                    'Options',{'OK'},'Icon','warning');
                switch selection
                    case 'OK'
                        delete(fig);
                end
                
            end
            
            if ~isequal(app.filefolder{app.idx_ref},app.filefolder{app.idx_tar})
                fig = uifigure;
                msg = 'Reference image and target image should be in the same folder!';
                title = 'ERROR';
                selection = uiconfirm(fig,msg,title,...
                    'Options',{'OK'},'Icon','warning');
                switch selection
                    case 'OK'
                        delete(fig);
                end
            end           
            app.image_tar_norm = normalized(app.image_tar,app.image_ref);
            [~,app.image_align,~,~] = kaze(rgb2gray(app.image_tar_norm),rgb2gray(app.image_ref),app.image_tar,0.004);
            imshow(app.image_align,'Parent',app.RegistrationAxes);
        end

        % Button pushed function: IlluminationNormalizationButton
        function normalization(app, event)
            app.image_tar_norm = normalized(app.image_tar,app.image_ref);
            imshow(app.image_tar_norm, 'parent', app.TarNormAxes);
        end

        % Value changed function: RegistrationofAllImagesButton
        function ShowAnimination(app, event)
            app.im_gif = gif_generation(app.image,app.date);
            while 1
                try
                    for i = 1:numel(app.im_gif)
                        imshow(app.im_gif{i},'parent',app.AnimationAxes);
                        pause(0.8);
                    end
                catch
                    %error occured, probably app was deleted. Quit loop
                    break;
                end                 
            end
        end

        % Value changed function: DifferentiationButton
        function differentiation(app, event)
            app.threshold = app.DifferentiationThresholdSlider.Value;
            [app.image_di,app.number_AlignedPixels,app.number_DifferentPixels] = differential(app.image_ref,app.image_align,app.threshold);
            imshow(app.image_di,'parent',app.DiffAxes);
        end

        % Value changed function: HighlightButton
        function Highlight(app, event)
            if isequal(app.image_di,[])
                [app.image_di,app.number_AlignedPixels,app.number_DifferentPixels] = differential(app.image_ref,app.image_align);
            end
            app.image_highlight = app.image_ref + uint8(cat(3,app.image_di*1*255,app.image_di*0*255,app.image_di*0*255));
            imshow(app.image_highlight,'parent',app.HighlightAxes);
        end

        % Value changing function: DifferentiationThresholdSlider
        function ChangingThreshold(app, event)
            app.threshold = event.Value;
            
        end

        % Button pushed function: EvaluateButton
        function ChangeAnalysis(app, event)
            year_ref = floor(app.date(app.idx_ref));
            year_tar = floor(app.date(app.idx_tar));
            month_ref = 100*(app.date(app.idx_ref)-year_ref);
            month_tar = 100*(app.date(app.idx_tar)-year_tar);
            app.num_month = abs(12*(year_tar-year_ref)+(month_tar-month_ref));
            
            [app.ch_ratio,app.ch_speed] = change_analysis(app.number_AlignedPixels,app.number_DifferentPixels,app.num_month);
            app.ValueArea.Value = sprintf([ 'Changing Time: ' num2str(app.num_month,'%.0f') ' month\n' ...
                'Changing ratio: ' num2str(app.ch_ratio*100,'%.4f') '%%\n' ...
                'Change speed: ' num2str(app.ch_speed*100,'%.4f') '%%/month\n'...
                ]);            
        end

        % Value changed function: DifferentiationThresholdSlider
        function ChangedThreshold(app, event)
            app.threshold = app.DifferentiationThresholdSlider.Value;
            [app.image_di,app.number_AlignedPixels,app.number_DifferentPixels] = differential(app.image_ref,app.image_align,app.threshold);
            imshow(app.image_di,'parent',app.DiffAxes);
        end

        % Button pushed function: HighlightEvaluateButton
        function Classification(app, event)
            [app.highlight_nature,app.highlight_urban,app.isUrban]=classification(app.image_tar);
            imshow(app.highlight_nature,'parent',app.NatureAxes);
            imshow(app.highlight_urban,'parent',app.UrbanAxes);
            if app.isUrban
                app.ClassTextArea.Value = sprintf('This area is urban.\n');
            else
                app.ClassTextArea.Value = sprintf('This area is nature.\n');
            end
        end

        % Button pushed function: ResetButton
        function Reset(app, event)
            arrayfun(@cla,findall(0,'type','axes'));
            arrayfun(@cla,findall(0,'type','panel'));
            app.date = {};
            app.im_gif = {};
            app.ReferenceDropDown.Items = {};
            app.TargetDropDown.Items = {};   
            app.CurrentfolderLabel.Text = 'Current folder: ';
            app.ValueArea.Value = sprintf('');
            app.ClassTextArea.Value = sprintf('');       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.QuantitativeIndicatorsPanel = uipanel(app.UIFigure);
            app.QuantitativeIndicatorsPanel.Title = 'Quantitative Indicators';
            app.ValueArea = uitextarea(app.QuantitativeIndicatorsPanel);
            app.EvaluateButton = uibutton(app.QuantitativeIndicatorsPanel, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeAnalysis, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.SelectfolderButton = uibutton(app.UIFigure, 'push');
            app.SelectfolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolder, true);
            app.SelectfolderButton.Text = 'Select folder';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Change Detection in Satellite Images';
            app.Group36 = uilabel(app.UIFigure);
            app.Group36.Text = 'Group 36';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SelectImageTab = uitab(app.TabGroup);
            app.SelectImageTab.Title = 'Select Image';
            app.ReferenceDropDownLabel = uilabel(app.SelectImageTab);
            app.ReferenceDropDownLabel.Text = 'Reference';
            app.ReferenceDropDown = uidropdown(app.SelectImageTab);
            app.ReferenceDropDown.Items = {};
            app.ReferenceDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseReference, true);
            app.ReferenceDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueRef, true);
            app.ReferenceDropDown.Value = {};
            app.TargetDropDownLabel = uilabel(app.SelectImageTab);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.SelectImageTab);
            app.TargetDropDown.Items = {};
            app.TargetDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseTarget, true);
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @ValueTar, true);
            app.TargetDropDown.Value = {};
            app.IlluminationNormalizationButton = uibutton(app.SelectImageTab, 'push');
            app.IlluminationNormalizationButton.ButtonPushedFcn = createCallbackFcn(app, @normalization, true);
            app.IlluminationNormalizationButton.Text = 'Illumination Normalization';
            app.TarAxes = uiaxes(app.SelectImageTab);
            title(app.TarAxes, 'Target')
            app.TarAxes.XTick = [];
            app.TarAxes.YTick = [];
            app.TarNormAxes = uiaxes(app.SelectImageTab);
            title(app.TarNormAxes, 'Target-Norm')
            app.TarNormAxes.XTick = [];
            app.TarNormAxes.YTick = [];
            app.RefAxes = uiaxes(app.SelectImageTab);
            title(app.RefAxes, 'Reference')
            app.RefAxes.XTick = [];
            app.RefAxes.YTick = [];
            app.TimeLapseTab = uitab(app.TabGroup);
            app.TimeLapseTab.Title = 'Time Lapse';
            app.TimeLapsePanel = uipanel(app.TimeLapseTab);
            app.ShowTimeLapseButton = uibutton(app.TimeLapseTab, 'push');
            app.ShowTimeLapseButton.ButtonPushedFcn = createCallbackFcn(app, @ShowTimeLapse, true);
            app.ShowTimeLapseButton.Text = 'Show Time Lapse';
            app.CurrentfolderLabel = uilabel(app.UIFigure);
            app.CurrentfolderLabel.Text = 'Current folder:';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.RegistrationTab = uitab(app.TabGroup2);
            app.RegistrationTab.Title = 'Registration';
            app.RegistrationofSelectedImagesButton = uibutton(app.RegistrationTab, 'state');
            app.RegistrationofSelectedImagesButton.ValueChangedFcn = createCallbackFcn(app, @Registration, true);
            app.RegistrationofSelectedImagesButton.Text = 'Registration of Selected Images';
            app.RegistrationofAllImagesButton = uibutton(app.RegistrationTab, 'state');
            app.RegistrationofAllImagesButton.ValueChangedFcn = createCallbackFcn(app, @ShowAnimination, true);
            app.RegistrationofAllImagesButton.Text = 'Registration of All Images';
            app.RegistrationAxes = uiaxes(app.RegistrationTab);
            title(app.RegistrationAxes, 'Registration')
            app.RegistrationAxes.XTick = [];
            app.RegistrationAxes.YTick = [];
            app.AnimationAxes = uiaxes(app.RegistrationTab);
            title(app.AnimationAxes, 'Animation')
            app.AnimationAxes.XTick = [];
            app.AnimationAxes.YTick = [];
            app.DifferentiationTab = uitab(app.TabGroup2);
            app.DifferentiationTab.Title = 'Differentiation';
            app.DifferentiationButton = uibutton(app.DifferentiationTab, 'state');
            app.DifferentiationButton.ValueChangedFcn = createCallbackFcn(app, @differentiation, true);
            app.DifferentiationButton.Text = 'Differentiation';
            app.HighlightButton = uibutton(app.DifferentiationTab, 'state');
            app.HighlightButton.ValueChangedFcn = createCallbackFcn(app, @Highlight, true);
            app.HighlightButton.Text = 'Hightlight';
            app.DifferentiationThresholdSliderLabel = uilabel(app.DifferentiationTab);
            app.DifferentiationThresholdSliderLabel.Text = 'Differentiation Threshold';
            app.DifferentiationThresholdSlider = uislider(app.DifferentiationTab);
            app.DifferentiationThresholdSlider.Limits = [40 120];
            app.DifferentiationThresholdSlider.MajorTicks = [40 60 80 100 120];
            app.DifferentiationThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ChangedThreshold, true);
            app.DifferentiationThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @ChangingThreshold, true);
            app.DifferentiationThresholdSlider.MinorTicks = [40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100 104 108 112 116 120];
            app.DifferentiationThresholdSlider.Value = 60;
            app.DiffAxes = uiaxes(app.DifferentiationTab);
            title(app.DiffAxes, 'Differentiation')
            app.DiffAxes.XTick = [];
            app.DiffAxes.YTick = [];
            app.HighlightAxes = uiaxes(app.DifferentiationTab);
            title(app.HighlightAxes, 'Change Information Highlight')
            app.HighlightAxes.XTick = [];
            app.HighlightAxes.YTick = [];
            app.ClassificationTab = uitab(app.TabGroup2);
            app.ClassificationTab.Title = 'Classification';
            app.HighlightEvaluateButton = uibutton(app.ClassificationTab, 'push');
            app.HighlightEvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @Classification, true);
            app.HighlightEvaluateButton.Text = 'Evaluate';
            app.ClassTextArea = uitextarea(app.ClassificationTab);
            app.UrbanAxes = uiaxes(app.ClassificationTab);
            title(app.UrbanAxes, 'Urban Information Highlight')
            app.UrbanAxes.XTick = [];
            app.UrbanAxes.YTick = [];
            app.NatureAxes = uiaxes(app.ClassificationTab);
            title(app.NatureAxes, 'Nature Informtion Highlight')
            app.NatureAxes.XTick = [];
            app.NatureAxes.YTick = [];
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @Reset, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end